classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfiguraoeTreinodaRNTab        matlab.ui.container.Tab
        fAtivacao1                      matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        nomeMelhorRede                  matlab.ui.control.EditField
        NomeEditFieldLabel              matlab.ui.control.Label
        sliderIteracoes                 matlab.ui.control.Slider
        IteraesSliderLabel              matlab.ui.control.Label
        segTrain                        matlab.ui.control.NumericEditField
        TrainEditFieldLabel             matlab.ui.control.Label
        nNeuronios                      matlab.ui.control.NumericEditField
        NdeNeurniospCamadaEditFieldLabel  matlab.ui.control.Label
        NdeCamadasDropDown              matlab.ui.control.DropDown
        NdeCamadasDropDownLabel         matlab.ui.control.Label
        TempoExecucao                   matlab.ui.control.CheckBox
        TargetdoDatasetLabel            matlab.ui.control.Label
        DropDownAtributos               matlab.ui.control.DropDown
        fTreino                         matlab.ui.control.EditField
        GuardaraMelhorRedeCheckBox      matlab.ui.control.CheckBox
        GuardarButton                   matlab.ui.control.Button
        ErroCheckBox                    matlab.ui.control.CheckBox
        GrficocomoDesempenhoCheckBox    matlab.ui.control.CheckBox
        MatrizdeConfusoCheckBox         matlab.ui.control.CheckBox
        VerLabel                        matlab.ui.control.Label
        RedeCheckBox                    matlab.ui.control.CheckBox
        TreinarButton                   matlab.ui.control.Button
        TreinaraRedeNeuronalCriadaLabel  matlab.ui.control.Label
        segTest                         matlab.ui.control.NumericEditField
        TrainEditFieldLabel_3           matlab.ui.control.Label
        segValidation                   matlab.ui.control.NumericEditField
        TrainEditFieldLabel_2           matlab.ui.control.Label
        SegmentaoLabel                  matlab.ui.control.Label
        FunodeTreinoLabel               matlab.ui.control.Label
        fAtivacao4                      matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        fAtivacao3                      matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        fAtivacao2                      matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        FunesdeAtivaoLabel              matlab.ui.control.Label
        CamadasLabel                    matlab.ui.control.Label
        ConfiguraodaRedeNeuronalLabel   matlab.ui.control.Label
        DatasetLabel                    matlab.ui.control.Label
        escolheDataset                  matlab.ui.control.Button
        UtilizaoeAvaliaodaRNTab         matlab.ui.container.Tab
        SmokingStatusEditField          matlab.ui.control.NumericEditField
        SmokingStatusEditFieldLabel     matlab.ui.control.Label
        ResidenceTypeEditField          matlab.ui.control.NumericEditField
        ResidenceTypeEditFieldLabel     matlab.ui.control.Label
        EverMarriedEditField            matlab.ui.control.NumericEditField
        EverMarriedEditFieldLabel       matlab.ui.control.Label
        HeartDiseaseEditField           matlab.ui.control.NumericEditField
        HeartDiseaseEditFieldLabel      matlab.ui.control.Label
        HypertensionEditField           matlab.ui.control.NumericEditField
        HypertensionEditFieldLabel      matlab.ui.control.Label
        GenderEditField                 matlab.ui.control.NumericEditField
        GenderEditFieldLabel            matlab.ui.control.Label
        DadosLabel                      matlab.ui.control.Label
        RedeLabel_2                     matlab.ui.control.Label
        escolheRede_2                   matlab.ui.control.Button
        AdvinharButton                  matlab.ui.control.Button
        BMIEditField                    matlab.ui.control.NumericEditField
        BMIEditFieldLabel               matlab.ui.control.Label
        AvgGlucoseEditField             matlab.ui.control.NumericEditField
        AvgGlucoseEditFieldLabel        matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        ClassificaodeNovosCasosLabel    matlab.ui.control.Label
        RedeLabel                       matlab.ui.control.Label
        escolheRede                     matlab.ui.control.Button
        GrficocomoDesempenhoCheckBox_2  matlab.ui.control.CheckBox
        MatrizdeConfusoCheckBox_2       matlab.ui.control.CheckBox
        VerLabel_2                      matlab.ui.control.Label
        RedeCheckBox_2                  matlab.ui.control.CheckBox
        ExecutarButton                  matlab.ui.control.Button
        DatasetLabel_2                  matlab.ui.control.Label
        escolheDataset_2                matlab.ui.control.Button
        UsarumaRedePrTreinadaLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        arquivoCSVSelecionado logical = false;
        arquivoCSVSelecionado2 logical = false;
        redeCarregada logical = false;
        redeCarregada2 logical = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: escolheDataset
        function escolheDatasetPushed(app, event)
           [filename, filepath] = uigetfile('*.csv', 'Escolher um arquivo .csv');
            if isequal(filename, 0)
                disp('Seleção de arquivo cancelada');
                return;
            end
            
            [~, ~, ext] = fileparts(filename);
            if ~isequal(ext, '.csv')
                errordlg('Por favor, selecione um arquivo .csv', 'Erro');
                return;
            end
            
            % Defina o texto do botão como o nome do arquivo selecionado
            app.escolheDataset.Text = filename;
            
            % Habilitar o botão Treinar
            app.TreinarButton.Enable = 'on';
            app.nomeMelhorRede.Enable ="on";
            
            % Defina o arquivo CSV selecionado como verdadeiro
            app.arquivoCSVSelecionado = true;
           
        end

        % Value changed function: NdeCamadasDropDown
        function NdeCamadasDropDownValueChanged(app, event)
            value = app.NdeCamadasDropDown.Value;
    
            % Desabilitar todas as EditFields de ativação
            app.fAtivacao1.Enable = 'off';
            app.fAtivacao2.Enable = 'off';
            app.fAtivacao3.Enable = 'off';
            app.fAtivacao4.Enable = 'off';
            app.Label_2.Enable = 'off';
            app.Label.Enable = 'off';
            app.Label_3.Enable = 'off';
            app.Label_4.Enable = 'off';
            app.nNeuronios.Enable = 'off';
            app.NdeNeurniospCamadaEditFieldLabel.Enable = 'off';
            app.FunodeTreinoLabel.Enable = 'on';
            %app.fTreinoDrop.Enable = 'off';
            app.fTreino.Enable = 'off';
            app.SegmentaoLabel.Enable = 'off';
            app.segTrain.Enable = 'off';
            app.TrainEditFieldLabel.Enable = 'off';
            app.TrainEditFieldLabel_2.Enable = 'off';
            app.TrainEditFieldLabel_3.Enable = 'off';
            app.segValidation.Enable = 'off';
            app.segTest.Enable = 'off';
            app.GuardarButton.Enable ="off";
    
            % Habilitar as EditFields de acordo com o número de camadas selecionado
            switch value
                case 'Escolher...'
                    app.fAtivacao1.Value = 'tansig';
                    app.fAtivacao2.Value = 'purelin';
                    app.fTreino.Value = 'trainlm'
                    app.fAtivacao3.Value = '';
                    app.fAtivacao4.Value = '';
                    
                case '1 Camada'
                    app.fAtivacao1.Enable = 'on';
                    app.fAtivacao2.Enable = 'on';
                    app.Label_2.Enable = 'on';
                    app.Label.Enable = 'on';
                    app.fAtivacao3.Value = '';
                    app.fAtivacao4.Value = '';
                    
                    %tudo
                    app.nNeuronios.Enable = 'on';
                    app.FunesdeAtivaoLabel.Enable = 'on';
                    app.NdeNeurniospCamadaEditFieldLabel.Enable = 'on';
                    app.FunodeTreinoLabel.Enable = 'on';
                    %app.fTreinoDrop.Enable = 'on';
                    app.fTreino.Enable = 'on';
                    app.SegmentaoLabel.Enable = 'on';
                    app.segTrain.Enable = 'on';
                    app.TrainEditFieldLabel.Enable = 'on';
                    app.TrainEditFieldLabel_2.Enable = 'on';
                    app.TrainEditFieldLabel_3.Enable = 'on';
                    app.segValidation.Enable = 'on';
                    app.segTest.Enable = 'on';
                    app.GuardarButton.Enable ="on";

                case '2 Camadas'
                    app.fAtivacao1.Enable = 'on';
                    app.fAtivacao2.Enable = 'on';
                    app.fAtivacao3.Enable = 'on';
                    app.Label_2.Enable = 'on';
                    app.Label.Enable = 'on';
                    app.Label_3.Enable = 'on';
                    app.fAtivacao4.Value = '';

                    %tudo
                    app.nNeuronios.Enable = 'on';
                    app.FunesdeAtivaoLabel.Enable = 'on';
                    app.NdeNeurniospCamadaEditFieldLabel.Enable = 'on';
                    app.FunodeTreinoLabel.Enable = 'on';
                    %app.fTreinoDrop.Enable = 'on';
                    app.fTreino.Enable = 'on';
                    app.SegmentaoLabel.Enable = 'on';
                    app.segTrain.Enable = 'on';
                    app.TrainEditFieldLabel.Enable = 'on';
                    app.TrainEditFieldLabel_2.Enable = 'on';
                    app.TrainEditFieldLabel_3.Enable = 'on';
                    app.segValidation.Enable = 'on';
                    app.segTest.Enable = 'on';
                    app.GuardarButton.Enable ="on";

                case '3 Camadas'
                    app.fAtivacao1.Enable = 'on';
                    app.fAtivacao2.Enable = 'on';
                    app.fAtivacao3.Enable = 'on';
                    app.fAtivacao4.Enable = 'on';
                    app.Label_2.Enable = 'on';
                    app.Label.Enable = 'on';
                    app.Label_3.Enable = 'on';
                    app.Label_4.Enable = 'on';

                    %tudo
                    app.nNeuronios.Enable = 'on';
                    app.FunesdeAtivaoLabel.Enable = 'on';
                    app.NdeNeurniospCamadaEditFieldLabel.Enable = 'on';
                    app.FunodeTreinoLabel.Enable = 'on';
                    %app.fTreinoDrop.Enable = 'on';
                    app.fTreino.Enable = 'on';
                    app.SegmentaoLabel.Enable = 'on';
                    app.segTrain.Enable = 'on';
                    app.TrainEditFieldLabel.Enable = 'on';
                    app.TrainEditFieldLabel_2.Enable = 'on';
                    app.TrainEditFieldLabel_3.Enable = 'on';
                    app.segValidation.Enable = 'on';
                    app.segTest.Enable = 'on';
                    app.GuardarButton.Enable ="on";
                    
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            nCamadas = app.NdeCamadasDropDown.Value;
            funcoesAtivacao = {'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'purelin', ...
                                  'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tansig', 'tribas'};
            funcoesTreino = {'trainbfg', 'trainbfgc', 'trainbr', 'trainbu', 'trainc', 'traincgb', 'traincgf', ...
                          'traincgp', 'traingd', 'traingda', 'traingdm', 'traingdx', 'trainlm', ...
                          'trainoss', 'trainr', 'trainrp', 'trainru', 'trains', 'trainscg'};
    
        
            switch nCamadas
                case 'Escolher...'
                    uialert(app.UIFigure, 'Todos os campos devem estar preenchidos.', 'Aviso', 'Icon', 'warning');
        
                case '1 Camada'
                    if isempty(app.nNeuronios.Value) || isempty(app.fAtivacao1.Value) || ...
                       isempty(app.fAtivacao2.Value) || ...
                       isempty(app.segTrain.Value) || isempty(app.segValidation.Value) || ...
                       isempty(app.segTest.Value)
                        uialert(app.UIFigure, 'Todos os campos devem estar preenchidos.', 'Aviso', 'Icon', 'warning');
                    elseif ~any(strcmp(app.fAtivacao1.Value, funcoesAtivacao))
                        uialert(app.UIFigure, 'Uma ou mais funções de ativação não existem.', 'Aviso', 'Icon', 'warning');
                    elseif ~any(strcmp(app.fTreino.Value, funcoesTreino))
                        uialert(app.UIFigure, 'A função de treino não existe.', 'Aviso', 'Icon', 'warning');
                    elseif abs(app.segTrain.Value + app.segValidation.Value + app.segTest.Value - 1) > eps
                        uialert(app.UIFigure, 'A soma de segTrain, segValidation e segTest deve ser igual a 1.', 'Aviso', 'Icon', 'warning');
                    else
                        uialert(app.UIFigure, 'A Rede Neuronal foi configurada com sucesso.', 'Aviso', 'Icon', 'success');
                        
                        app.DatasetLabel.Enable = 'on';
                        app.escolheDataset.Enable = 'on';
                        app.IteraesSliderLabel.Enable = 'on';
                        app.sliderIteracoes.Enable = 'on';
                        app.GuardaraMelhorRedeCheckBox.Enable ="on";
                        app.VerLabel.Enable ="on";
                        app.RedeCheckBox.Enable ="on";
                        app.MatrizdeConfusoCheckBox.Enable="on";
                        app.GrficocomoDesempenhoCheckBox.Enable="on";
                        app.ErroCheckBox.Enable="on";
                        app.TempoExecucao.Enable="on";
                        app.NomeEditFieldLabel.Enable="on";
                        app.nomeMelhorRede.Enable="on";
                        %app.TreinarButton.Enable="on";
                    end
        
                case '2 Camadas'
                    if isempty(app.nNeuronios.Value) || isempty(app.fAtivacao1.Value) || ...
                       isempty(app.fAtivacao2.Value) || ...
                       isempty(app.segTrain.Value) || isempty(app.segValidation.Value) || ...
                       isempty(app.segTest.Value) || isempty(app.fAtivacao3.Value)
                        uialert(app.UIFigure, 'Todos os campos devem estar preenchidos.', 'Aviso', 'Icon', 'warning');
                    elseif ~any(strcmp(app.fAtivacao1.Value, funcoesAtivacao)) || ~any(strcmp(app.fAtivacao2.Value, funcoesAtivacao))
                        uialert(app.UIFigure, 'Uma ou mais funções de ativação não existem.', 'Aviso', 'Icon', 'warning');
                    elseif abs(app.segTrain.Value + app.segValidation.Value + app.segTest.Value - 1) > eps
                        uialert(app.UIFigure, 'A soma de segTrain, segValidation e segTest deve ser igual a 1.', 'Aviso', 'Icon', 'warning');
                    elseif ~any(strcmp(app.fTreino.Value, funcoesTreino))
                        uialert(app.UIFigure, 'A função de treino não existe.', 'Aviso', 'Icon', 'warning');
                    else
                        uialert(app.UIFigure, 'A Rede Neuronal foi configurada com sucesso.', 'Aviso', 'Icon', 'success');
                    
                        app.DatasetLabel.Enable = 'on';
                        app.escolheDataset.Enable = 'on';
                        app.IteraesSliderLabel.Enable = 'on';
                        app.sliderIteracoes.Enable = 'on';
                        app.GuardaraMelhorRedeCheckBox.Enable ="on";
                        app.VerLabel.Enable ="on";
                        app.RedeCheckBox.Enable ="on";
                        app.MatrizdeConfusoCheckBox.Enable="on";
                        app.GrficocomoDesempenhoCheckBox.Enable="on";
                        app.ErroCheckBox.Enable="on";
                        app.TempoExecucao.Enable="on";
                        app.nomeMelhorRede.Enable="on";
                        app.NomeEditFieldLabel.Enable="on";
                        %app.TreinarButton.Enable="on";
                    end
        
                case '3 Camadas'
                    if isempty(app.nNeuronios.Value) || isempty(app.fAtivacao1.Value) || ...
                       isempty(app.fAtivacao2.Value) || ...
                       isempty(app.segTrain.Value) || isempty(app.segValidation.Value) || ...
                       isempty(app.segTest.Value) || isempty(app.fAtivacao3.Value) || isempty(app.fAtivacao4.Value)
                        uialert(app.UIFigure, 'Todos os campos devem estar preenchidos.', 'Aviso', 'Icon', 'warning');
                    elseif ~any(strcmp(app.fAtivacao1.Value, funcoesAtivacao)) || ~any(strcmp(app.fAtivacao2.Value, funcoesAtivacao)) || ...
                           ~any(strcmp(app.fAtivacao3.Value, funcoesAtivacao)) || ~any(strcmp(app.fAtivacao4.Value, funcoesAtivacao))
                        uialert(app.UIFigure, 'Uma ou mais funções de ativação não existem.', 'Aviso', 'Icon', 'warning');
                    elseif abs(app.segTrain.Value + app.segValidation.Value + app.segTest.Value - 1) > eps
                        uialert(app.UIFigure, 'A soma de segTrain, segValidation e segTest deve ser igual a 1.', 'Aviso', 'Icon', 'warning');
                    elseif ~any(strcmp(app.fTreino.Value, funcoesTreino))
                        uialert(app.UIFigure, 'A função de treino não existe.', 'Aviso', 'Icon', 'warning');
                    else
                        uialert(app.UIFigure, 'A Rede Neuronal foi configurada com sucesso.', 'Aviso', 'Icon', 'success');
                    
                        app.DatasetLabel.Enable = 'on';
                        app.escolheDataset.Enable = 'on';
                        app.IteraesSliderLabel.Enable = 'on';
                        app.sliderIteracoes.Enable = 'on';
                        app.GuardaraMelhorRedeCheckBox.Enable ="on";
                        app.VerLabel.Enable ="on";
                        app.RedeCheckBox.Enable ="on";
                        app.MatrizdeConfusoCheckBox.Enable="on";
                        app.GrficocomoDesempenhoCheckBox.Enable="on";
                        app.ErroCheckBox.Enable="on";
                        app.nomeMelhorRede.Enable="on";
                        app.NomeEditFieldLabel.Enable="on";
                        app.TempoExecucao.Enable="on";
                        %app.TreinarButton.Enable="on";
                    end
        
            end
        end

        % Value changed function: GuardaraMelhorRedeCheckBox
        function GuardaraMelhorRedeCheckBoxValueChanged(app, event)
            value = app.GuardaraMelhorRedeCheckBox.Value;
    
            if value == 1
                app.NomeEditFieldLabel.Enable = 'on';
                app.nomeMelhorRede.Enable = 'on';
            else
                app.nomeMelhorRede.Enable = 'off';
                app.NomeEditFieldLabel.Enable = 'off';
            end
        end

        % Button pushed function: TreinarButton
        function TreinarButtonPushed(app, event)
            if ~app.arquivoCSVSelecionado
                uialert(app.UIFigure, 'Por favor, selecione um dataset .csv antes de treinar a rede.', 'Aviso', 'Icon', 'warning');
                return;
            end
            
            if app.GuardaraMelhorRedeCheckBox.Value == 1 && isempty(app.nomeMelhorRede.Value)
                uialert(app.UIFigure, 'Por favor, insira um nome para salvar a melhor rede.', 'Aviso', 'Icon', 'warning');
                return;
            end
        
            % if strcmp(app.DropDownAtributos.Value,"Escolher...")
            %     uialert(app.UIFigure, 'Por favor, escolha o target do dataset.', 'Aviso', 'Icon', 'warning');
            %     return;
            % end
            
            uialert(app.UIFigure, 'A processar...', 'Aviso', 'Icon', 'success');
            
            % Captura a saída da função trainAPP
            output_message = evalc('trainAPP(app)');
            
            % Cria uma janela de mensagem com texto editável
            outputDlg = dialog('Name', 'Output', 'WindowStyle', 'normal', 'Position', [100, 100, 600, 500]);
            txt = uicontrol('Parent', outputDlg, 'Style', 'edit', 'Units', 'normalized', 'Position', [0.05, 0.05, 0.9, 0.9], ...
                            'Max', 2, 'HorizontalAlignment', 'left', 'FontSize', 12, 'String', output_message);
        end

        % Button pushed function: escolheRede
        function escolheRedeButtonPushed(app, event)
            initialPath = '../testes/redes/'; 

            [filename, filepath] = uigetfile(fullfile(initialPath, '*.mat'), 'Escolher um arquivo .mat');
            if isequal(filename, 0)
                disp('Seleção de arquivo cancelada');
                return;
            end
            
            [~, ~, ext] = fileparts(filename);
            if ~isequal(ext, '.mat')
                errordlg('Por favor, selecione um arquivo .mat', 'Erro');
                return;
            end
            
            % Defina o texto do botão como o nome do arquivo selecionado
            app.escolheRede.Text = filename;
            
            % Defina o arquivo CSV selecionado como verdadeiro
            app.redeCarregada = true;

            if app.arquivoCSVSelecionado2 == true && app.redeCarregada == true
                app.ExecutarButton.Enable = "on";
            end
        end

        % Button pushed function: escolheDataset_2
        function escolheDataset_2ButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.csv', 'Escolher um arquivo .csv');
            if isequal(filename, 0)
                disp('Seleção de arquivo cancelada');
                return;
            end
            
            [~, ~, ext] = fileparts(filename);
            if ~isequal(ext, '.csv')
                errordlg('Por favor, selecione um arquivo .csv', 'Erro');
                return;
            end
            
            % Defina o texto do botão como o nome do arquivo selecionado
            app.escolheDataset_2.Text = filename;
            
            % Defina o arquivo CSV selecionado como verdadeiro
            app.arquivoCSVSelecionado2 = true;

            if app.arquivoCSVSelecionado2 == true && app.redeCarregada == true
                app.ExecutarButton.Enable = "on";
            end
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            uialert(app.UIFigure, 'A processar...', 'Aviso', 'Icon', 'success');
            
            % Captura a saída da função trainAPP
            output_message = evalc('testAPP(app)');
            % Cria uma janela de mensagem com texto editável
            outputDlg = dialog('Name', 'Output', 'WindowStyle', 'normal', 'Position', [100, 100, 600, 100]);
            txt = uicontrol('Parent', outputDlg, 'Style', 'edit', 'Units', 'normalized', 'Position', [0.05, 0.05, 0.9, 0.9], ...
                            'Max', 2, 'HorizontalAlignment', 'left', 'FontSize', 12, 'String', output_message);
        end

        % Button pushed function: escolheRede_2
        function escolheRede_2ButtonPushed(app, event)
            initialPath = '../testes/redes/'; 

            [filename, filepath] = uigetfile(fullfile(initialPath, '*.mat'), 'Escolher um arquivo .mat');
            if isequal(filename, 0)
                disp('Seleção de arquivo cancelada');
                return;
            end
            
            [~, ~, ext] = fileparts(filename);
            if ~isequal(ext, '.mat')
                errordlg('Por favor, selecione um arquivo .mat', 'Erro');
                return;
            end
            
            % Defina o texto do botão como o nome do arquivo selecionado
            app.escolheRede_2.Text = filename;
            
            % Defina o arquivo CSV selecionado como verdadeiro
            app.redeCarregada2 = true;

            if app.redeCarregada2 == true
                app.AdvinharButton.Enable = "on";
            end
        end

        % Button pushed function: AdvinharButton
        function AdvinharButtonPushed(app, event)

            uialert(app.UIFigure, 'A processar...', 'Aviso', 'Icon', 'success');

            % Captura a saída da função trainAPP
            %output_message = evalc('adivinhaAPP(app)');
            adivinhaAPP(app);
            % Cria uma janela de mensagem com texto editável
            %outputDlg = dialog('Name', 'Output', 'WindowStyle', 'normal', 'Position', [100, 100, 600, 100]);
            %txt = uicontrol('Parent', outputDlg, 'Style', 'edit', 'Units', 'normalized', 'Position', [0.05, 0.05, 0.9, 0.9], ...
                            %'Max', 2, 'HorizontalAlignment', 'left', 'FontSize', 12, 'String', output_message);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfiguraoeTreinodaRNTab = uitab(app.TabGroup);
            app.ConfiguraoeTreinodaRNTab.Title = 'Configuração e Treino da RN';
            app.escolheDataset = uibutton(app.ConfiguraoeTreinodaRNTab, 'push');
            app.escolheDataset.ButtonPushedFcn = createCallbackFcn(app, @escolheDatasetPushed, true);
            app.escolheDataset.Enable = 'off';
            app.escolheDataset.Text = 'Escolher um ficheiro...';
            app.DatasetLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.DatasetLabel.Enable = 'off';
            app.DatasetLabel.Text = 'Dataset:';
            app.ConfiguraodaRedeNeuronalLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.ConfiguraodaRedeNeuronalLabel.Text = 'Configuração da Rede Neuronal';
            app.CamadasLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.CamadasLabel.Text = 'Camada(s)';
            app.FunesdeAtivaoLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.FunesdeAtivaoLabel.Enable = 'off';
            app.FunesdeAtivaoLabel.Text = 'Funções de Ativação';
            app.Label_2 = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '#2';
            app.fAtivacao2 = uieditfield(app.ConfiguraoeTreinodaRNTab, 'text');
            app.fAtivacao2.Tag = 'fAtivacao2';
            app.fAtivacao2.Enable = 'off';
            app.fAtivacao2.Value = 'purelin';
            app.Label_3 = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '#3';
            app.fAtivacao3 = uieditfield(app.ConfiguraoeTreinodaRNTab, 'text');
            app.fAtivacao3.Tag = 'fAtivacao3';
            app.fAtivacao3.Enable = 'off';
            app.Label_4 = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '#4';
            app.fAtivacao4 = uieditfield(app.ConfiguraoeTreinodaRNTab, 'text');
            app.fAtivacao4.Tag = 'fAtivacao4';
            app.fAtivacao4.Enable = 'off';
            app.FunodeTreinoLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.FunodeTreinoLabel.Enable = 'off';
            app.FunodeTreinoLabel.Text = 'Função de Treino';
            app.SegmentaoLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.SegmentaoLabel.Enable = 'off';
            app.SegmentaoLabel.Text = 'Segmentação';
            app.TrainEditFieldLabel_2 = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.TrainEditFieldLabel_2.Enable = 'off';
            app.TrainEditFieldLabel_2.Text = 'Validation';
            app.segValidation = uieditfield(app.ConfiguraoeTreinodaRNTab, 'numeric');
            app.segValidation.Tag = 'segValidation';
            app.segValidation.Enable = 'off';
            app.segValidation.Value = 0.15;
            app.TrainEditFieldLabel_3 = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.TrainEditFieldLabel_3.Enable = 'off';
            app.TrainEditFieldLabel_3.Text = 'Test';
            app.segTest = uieditfield(app.ConfiguraoeTreinodaRNTab, 'numeric');
            app.segTest.Tag = 'segTest';
            app.segTest.Enable = 'off';
            app.segTest.Value = 0.15;
            app.TreinaraRedeNeuronalCriadaLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.TreinaraRedeNeuronalCriadaLabel.Text = 'Treinar a Rede Neuronal Criada';
            app.TreinarButton = uibutton(app.ConfiguraoeTreinodaRNTab, 'push');
            app.TreinarButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarButtonPushed, true);
            app.TreinarButton.Enable = 'off';
            app.TreinarButton.Text = 'Treinar';
            app.RedeCheckBox = uicheckbox(app.ConfiguraoeTreinodaRNTab);
            app.RedeCheckBox.Enable = 'off';
            app.RedeCheckBox.Text = 'Rede';
            app.VerLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.VerLabel.Enable = 'off';
            app.VerLabel.Text = 'Ver:';
            app.MatrizdeConfusoCheckBox = uicheckbox(app.ConfiguraoeTreinodaRNTab);
            app.MatrizdeConfusoCheckBox.Enable = 'off';
            app.MatrizdeConfusoCheckBox.Text = 'Matriz de Confusão';
            app.GrficocomoDesempenhoCheckBox = uicheckbox(app.ConfiguraoeTreinodaRNTab);
            app.GrficocomoDesempenhoCheckBox.Enable = 'off';
            app.GrficocomoDesempenhoCheckBox.Text = 'Gráfico com o Desempenho';
            app.ErroCheckBox = uicheckbox(app.ConfiguraoeTreinodaRNTab);
            app.ErroCheckBox.Enable = 'off';
            app.ErroCheckBox.Text = 'Erro';
            app.ErroCheckBox.Value = true;
            app.GuardarButton = uibutton(app.ConfiguraoeTreinodaRNTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Tag = 'criarRede';
            app.GuardarButton.Enable = 'off';
            app.GuardarButton.Text = 'Guardar';
            app.GuardaraMelhorRedeCheckBox = uicheckbox(app.ConfiguraoeTreinodaRNTab);
            app.GuardaraMelhorRedeCheckBox.ValueChangedFcn = createCallbackFcn(app, @GuardaraMelhorRedeCheckBoxValueChanged, true);
            app.GuardaraMelhorRedeCheckBox.Enable = 'off';
            app.GuardaraMelhorRedeCheckBox.Text = 'Guardar a Melhor Rede';
            app.GuardaraMelhorRedeCheckBox.Value = true;
            app.fTreino = uieditfield(app.ConfiguraoeTreinodaRNTab, 'text');
            app.fTreino.Enable = 'off';
            app.fTreino.Value = 'trainlm';
            app.DropDownAtributos = uidropdown(app.ConfiguraoeTreinodaRNTab);
            app.DropDownAtributos.Visible = 'off';
            app.TargetdoDatasetLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.TargetdoDatasetLabel.Visible = 'off';
            app.TargetdoDatasetLabel.Text = 'Target do Dataset:';
            app.TempoExecucao = uicheckbox(app.ConfiguraoeTreinodaRNTab);
            app.TempoExecucao.Enable = 'off';
            app.TempoExecucao.Text = 'Tempo de Execução';
            app.TempoExecucao.Value = true;
            app.NdeCamadasDropDownLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.NdeCamadasDropDownLabel.Text = 'Nº de Camadas';
            app.NdeCamadasDropDown = uidropdown(app.ConfiguraoeTreinodaRNTab);
            app.NdeCamadasDropDown.Items = {'Escolher...', '1 Camada', '2 Camadas', '3 Camadas'};
            app.NdeCamadasDropDown.ValueChangedFcn = createCallbackFcn(app, @NdeCamadasDropDownValueChanged, true);
            app.NdeCamadasDropDown.Tag = 'nCamadas';
            app.NdeCamadasDropDown.Value = 'Escolher...';
            app.NdeNeurniospCamadaEditFieldLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.NdeNeurniospCamadaEditFieldLabel.Enable = 'off';
            app.NdeNeurniospCamadaEditFieldLabel.Text = 'Nº de Neurónios p/ Camada';
            app.nNeuronios = uieditfield(app.ConfiguraoeTreinodaRNTab, 'numeric');
            app.nNeuronios.Tag = 'nNeuronios';
            app.nNeuronios.Enable = 'off';
            app.nNeuronios.Value = 10;
            app.TrainEditFieldLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.TrainEditFieldLabel.Enable = 'off';
            app.TrainEditFieldLabel.Text = 'Train';
            app.segTrain = uieditfield(app.ConfiguraoeTreinodaRNTab, 'numeric');
            app.segTrain.Tag = 'segTrain';
            app.segTrain.Enable = 'off';
            app.segTrain.Value = 0.7;
            app.IteraesSliderLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.IteraesSliderLabel.Enable = 'off';
            app.IteraesSliderLabel.Text = 'Iterações';
            app.sliderIteracoes = uislider(app.ConfiguraoeTreinodaRNTab);
            app.sliderIteracoes.Limits = [1 50];
            app.sliderIteracoes.MajorTicks = [1 10 20 30 40 50];
            app.sliderIteracoes.MajorTickLabels = {'1', '10', '20', '30', '40', '50'};
            app.sliderIteracoes.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.sliderIteracoes.Enable = 'off';
            app.sliderIteracoes.Value = 50;
            app.NomeEditFieldLabel = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.NomeEditFieldLabel.Enable = 'off';
            app.NomeEditFieldLabel.Text = 'Nome';
            app.nomeMelhorRede = uieditfield(app.ConfiguraoeTreinodaRNTab, 'text');
            app.nomeMelhorRede.Enable = 'off';
            app.Label = uilabel(app.ConfiguraoeTreinodaRNTab);
            app.Label.Enable = 'off';
            app.Label.Text = '#1';
            app.fAtivacao1 = uieditfield(app.ConfiguraoeTreinodaRNTab, 'text');
            app.fAtivacao1.Tag = 'fAtivacao1';
            app.fAtivacao1.Enable = 'off';
            app.fAtivacao1.Value = 'tansig';
            app.UtilizaoeAvaliaodaRNTab = uitab(app.TabGroup);
            app.UtilizaoeAvaliaodaRNTab.Title = 'Utilização e Avaliação da RN';
            app.UsarumaRedePrTreinadaLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.UsarumaRedePrTreinadaLabel.Text = 'Usar uma Rede Pré-Treinada';
            app.escolheDataset_2 = uibutton(app.UtilizaoeAvaliaodaRNTab, 'push');
            app.escolheDataset_2.ButtonPushedFcn = createCallbackFcn(app, @escolheDataset_2ButtonPushed, true);
            app.escolheDataset_2.Text = 'Escolher um ficheiro...';
            app.DatasetLabel_2 = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.DatasetLabel_2.Text = 'Dataset:';
            app.ExecutarButton = uibutton(app.UtilizaoeAvaliaodaRNTab, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Enable = 'off';
            app.ExecutarButton.Text = 'Executar';
            app.RedeCheckBox_2 = uicheckbox(app.UtilizaoeAvaliaodaRNTab);
            app.RedeCheckBox_2.Text = 'Rede';
            app.VerLabel_2 = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.VerLabel_2.Text = 'Ver:';
            app.MatrizdeConfusoCheckBox_2 = uicheckbox(app.UtilizaoeAvaliaodaRNTab);
            app.MatrizdeConfusoCheckBox_2.Text = 'Matriz de Confusão';
            app.GrficocomoDesempenhoCheckBox_2 = uicheckbox(app.UtilizaoeAvaliaodaRNTab);
            app.GrficocomoDesempenhoCheckBox_2.Text = 'Gráfico com o Desempenho';
            app.escolheRede = uibutton(app.UtilizaoeAvaliaodaRNTab, 'push');
            app.escolheRede.ButtonPushedFcn = createCallbackFcn(app, @escolheRedeButtonPushed, true);
            app.escolheRede.Text = 'Escolher um ficheiro...';
            app.RedeLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.RedeLabel.Text = 'Rede:';
            app.ClassificaodeNovosCasosLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.ClassificaodeNovosCasosLabel.Text = 'Classificação de Novos Casos';
            app.AgeEditFieldLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UtilizaoeAvaliaodaRNTab, 'numeric');
            app.AgeEditField.AllowEmpty = 'on';
            app.AgeEditField.Value = 79;
            app.AvgGlucoseEditFieldLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.AvgGlucoseEditFieldLabel.Text = 'Avg Glucose';
            app.AvgGlucoseEditField = uieditfield(app.UtilizaoeAvaliaodaRNTab, 'numeric');
            app.AvgGlucoseEditField.AllowEmpty = 'on';
            app.AvgGlucoseEditField.Value = 174.12;
            app.BMIEditFieldLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.BMIEditFieldLabel.Text = 'BMI';
            app.BMIEditField = uieditfield(app.UtilizaoeAvaliaodaRNTab, 'numeric');
            app.BMIEditField.AllowEmpty = 'on';
            app.BMIEditField.Value = 24;
            app.AdvinharButton = uibutton(app.UtilizaoeAvaliaodaRNTab, 'push');
            app.AdvinharButton.ButtonPushedFcn = createCallbackFcn(app, @AdvinharButtonPushed, true);
            app.AdvinharButton.Enable = 'off';
            app.AdvinharButton.Text = 'Advinhar';
            app.escolheRede_2 = uibutton(app.UtilizaoeAvaliaodaRNTab, 'push');
            app.escolheRede_2.ButtonPushedFcn = createCallbackFcn(app, @escolheRede_2ButtonPushed, true);
            app.escolheRede_2.Text = 'Escolher um ficheiro...';
            app.RedeLabel_2 = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.RedeLabel_2.Text = 'Rede:';
            app.DadosLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.DadosLabel.Text = 'Dados:';
            app.GenderEditFieldLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.GenderEditFieldLabel.Text = 'Gender';
            app.GenderEditField = uieditfield(app.UtilizaoeAvaliaodaRNTab, 'numeric');
            app.GenderEditField.Limits = [0 1];
            app.GenderEditField.RoundFractionalValues = 'on';
            app.GenderEditField.Value = 1;
            app.HypertensionEditFieldLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.HypertensionEditFieldLabel.Text = 'Hypertension';
            app.HypertensionEditField = uieditfield(app.UtilizaoeAvaliaodaRNTab, 'numeric');
            app.HypertensionEditField.Limits = [0 1];
            app.HypertensionEditField.RoundFractionalValues = 'on';
            app.HypertensionEditField.Value = 1;
            app.HeartDiseaseEditFieldLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.HeartDiseaseEditFieldLabel.Text = 'Heart Disease';
            app.HeartDiseaseEditField = uieditfield(app.UtilizaoeAvaliaodaRNTab, 'numeric');
            app.HeartDiseaseEditField.Limits = [0 1];
            app.HeartDiseaseEditField.RoundFractionalValues = 'on';
            app.EverMarriedEditFieldLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.EverMarriedEditFieldLabel.Text = 'Ever Married';
            app.EverMarriedEditField = uieditfield(app.UtilizaoeAvaliaodaRNTab, 'numeric');
            app.EverMarriedEditField.Limits = [0 1];
            app.EverMarriedEditField.RoundFractionalValues = 'on';
            app.EverMarriedEditField.Value = 1;
            app.ResidenceTypeEditFieldLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.ResidenceTypeEditFieldLabel.Text = 'Residence Type';
            app.ResidenceTypeEditField = uieditfield(app.UtilizaoeAvaliaodaRNTab, 'numeric');
            app.ResidenceTypeEditField.Limits = [0 1];
            app.ResidenceTypeEditField.RoundFractionalValues = 'on';
            app.SmokingStatusEditFieldLabel = uilabel(app.UtilizaoeAvaliaodaRNTab);
            app.SmokingStatusEditFieldLabel.Text = 'Smoking Status';
            app.SmokingStatusEditField = uieditfield(app.UtilizaoeAvaliaodaRNTab, 'numeric');
            app.SmokingStatusEditField.Limits = [0 3];
            app.SmokingStatusEditField.RoundFractionalValues = 'on';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end